classdef MyApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Slider           matlab.ui.control.Slider
        SliderLabel      matlab.ui.control.Label
        aEditField       matlab.ui.control.EditField
        aEditFieldLabel  matlab.ui.control.Label
        yax2Button       matlab.ui.control.Button
        ycosxButton      matlab.ui.control.Button
        ysinxButton      matlab.ui.control.Button
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ysinxButton
        function ysinxButtonPushed(app, event)
            x = linspace(0, 2*pi, 100);
            y = sin(x);
            plot(app.UIAxes, x, y);
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'sin(x)');
            title(app.UIAxes, 'y = sin(x)');
            app.UIAxes.XLim = [0, 2*pi];
            app.UIAxes.YLim = [-1, 1];
        end

        % Button pushed function: ycosxButton
        function ycosxButtonPushed(app, event)
            x = linspace(0, 2*pi, 100);
            y = cos(x);
            plot(app.UIAxes, x, y);
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'cos(x)');
            title(app.UIAxes, 'y = cos(x)');
            app.UIAxes.XLim = [0, 2*pi];
            app.UIAxes.YLim = [-1, 1];
        end

        % Button pushed function: yax2Button
        function yax2ButtonPushed(app, event)
            x = linspace(-10, 10, 1000);
            a = app.Slider.Value;
            y = a*x.^2;
            plot(app.UIAxes, x, y);
            xlabel(app.UIAxes, 'x');
            ylabel(app.UIAxes, 'ax^2');
            title(app.UIAxes, 'y = ax^2');
            app.UIAxes.XLim = [-10, 10];
            app.UIAxes.YLim = [0, 100];
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.aEditField.Value = num2str(app.Slider.Value);
            yax2ButtonPushed(app, event);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ysinxButton = uibutton(app.UIFigure, 'push');
            app.ysinxButton.ButtonPushedFcn = createCallbackFcn(app, @ysinxButtonPushed, true);
            app.ysinxButton.Text = 'y=sin(x)';
            app.ycosxButton = uibutton(app.UIFigure, 'push');
            app.ycosxButton.ButtonPushedFcn = createCallbackFcn(app, @ycosxButtonPushed, true);
            app.ycosxButton.Text = 'y=cos(x)';
            app.yax2Button = uibutton(app.UIFigure, 'push');
            app.yax2Button.ButtonPushedFcn = createCallbackFcn(app, @yax2ButtonPushed, true);
            app.yax2Button.Text = 'y=ax^2';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'text');
            app.aEditField.Value = '1';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0.1 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end